classdef MRI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SelectInitialImageFolderButton  matlab.ui.control.Button
        ClicktoRunImageAnalysisButton   matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        AnalyzingImageLabel             matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        NextImageButton                 matlab.ui.control.Button
        RetryImageButton                matlab.ui.control.Button
        orLabel                         matlab.ui.control.Label
    end

    
    properties (Access = public)
        ImgStack % tif image file
        t % imread tif file
        final_disc % final isolated disc
        final_overlay % final overlayed image
        finalfolderpath
    end
    
    properties (Access = private)
        stacklength % number of slices in image stack
        files
        next_image % next image button
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectInitialImageFolderButton
        function SelectInitialImageFolderButtonPushed(app, event)
            folderpath = uigetdir('../.tif','Select initial image .tiff folder');
%             app.files = dir(folderpath);
            app.files = dir(fullfile(folderpath,'*.tif')); %gets all tiff files in struct
%             app.ImgStack = filename;
%             app.t = Tiff(app.ImgStack,'r');                   % open and read tif file
% %             app.t = imread(app.ImgStack)
%             Fullname = [filepath filename];

%             % Now read that image (fullname)           
%             for i = 1:50                                        % set up for loop 
%                 setDirectory(app.t,i)                               % set up 1st IFD
%                 if lastDirectory(app.t) == 0                        % if IFD isn't last IFD in stack force next iteration
%                     continue
%                 end
%                 if lastDirectory(app.t) == 1                        % check if it is the last IFD in stack
%                     app.stacklength = i;                           % set length of stack
%                     break
%                 end
%             end
%             
%             % Display the image
%             for i = 1:app.stacklength                               % initiate for loop
%                  A(:,:,i) = imread(app.ImgStack,i);
%             end
%             montage(A,'Parent',app.UIAxes);
%             clear axes scale
%             axis off
%             set(app.Label,'text',foldername);
        end

        % Button pushed function: ClicktoRunImageAnalysisButton
        function ClicktoRunImageAnalysisButtonPushed(app, event)
            for i = 1:length(app.files)
                if i==1
                    set(app.Label,'text',app.files(i).name)
                    app.ImgStack = app.files(i).name;
                    [app.final_disc, app.final_overlay,app.stacklength] = MRI_Segmenter(app.ImgStack);
                    montage(app.final_overlay,'Parent',app.UIAxes);
                    clear axes scale
                    axis off
                elseif i>1
                    waitfor(app.NextImageButton,'app.next_image','1')
                    set(app.Label,'text',app.files(i).name)
                    app.ImgStack = app.files(i).name;
                    [app.final_disc, app.final_overlay,app.stacklength] = MRI_Segmenter(app.ImgStack);
                    montage(app.final_overlay,'Parent',app.UIAxes);
                    clear axes scale
                    axis off
                    app.next_image = '0';                    
                end
            end
        end

        % Callback function
        function SelectResultsDestinationFolderButtonPushed(app, event)
            
        end

        % Button pushed function: NextImageButton
        function NextImageButtonPushed(app, event)
            app.next_image = '1';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SelectInitialImageFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectInitialImageFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectInitialImageFolderButtonPushed, true);
            app.SelectInitialImageFolderButton.Text = 'Select Initial Image Folder';
            app.ClicktoRunImageAnalysisButton = uibutton(app.UIFigure, 'push');
            app.ClicktoRunImageAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktoRunImageAnalysisButtonPushed, true);
            app.ClicktoRunImageAnalysisButton.Text = 'Click to Run Image Analysis';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'MRI Image')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.AnalyzingImageLabel = uilabel(app.UIFigure);
            app.AnalyzingImageLabel.Text = 'Analyzing Image:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.NextImageButton = uibutton(app.UIFigure, 'push');
            app.NextImageButton.ButtonPushedFcn = createCallbackFcn(app, @NextImageButtonPushed, true);
            app.NextImageButton.Text = 'Next Image';
            app.RetryImageButton = uibutton(app.UIFigure, 'push');
            app.RetryImageButton.Text = 'Retry Image';
            app.orLabel = uilabel(app.UIFigure);
            app.orLabel.Text = 'or';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MRI_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end